classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        YEditField_2               matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint4Slider               matlab.ui.control.Slider
        Joint4SliderLabel          matlab.ui.control.Label
        Joint5Slider               matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        Joint6Slider               matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        EMERGENCYSTOPButton        matlab.ui.control.Button
        DISENGAGEESTOPButton       matlab.ui.control.Button
        EditField_6                matlab.ui.control.NumericEditField
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        RESUMEButton               matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        Joint1Slider               matlab.ui.control.Slider
        Joint1SliderLabel          matlab.ui.control.Label
        MakeLoadedUR3eMoveButton   matlab.ui.control.Button
        MakeLoadedWidowMoveButton  matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % place your class properties here
        modelWidow % Where I will put the loaded Widow
        modelUR3e % Where I will put the loaded UR3e
        qz = [0     0     0     0     0     0];

        qrWidow = deg2rad([-15, 42.3, -5.9, -6.17, 52.4, -50.3]);
        qrUR3e = deg2rad([0, -20, 40, -110, -90, 0]);

        Widowfin1 = deg2rad([-180, -14.6, 26.9, 2.18, 76.3, -24.4]);
        UR3efin2 = deg2rad([-180, -50, 90, -130, -90, 0]);

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;
            hold on;

            % Make, save and plot some robots
            app.modelUR3e = UR3e;
            app.modelUR3e.model.base = transl([-0.5,0,0]);
            app.modelUR3e.model.animate(app.qrUR3e);

            app.modelWidow = WidowX250;
            app.modelWidow.model.base = transl([0.5,0,0]);
            app.modelWidow.model.animate(app.qrWidow);

            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.MakeLoadedWidowMoveButton.Enable = true;
            app.MakeLoadedUR3eMoveButton.Enable = true;

        end

        % Button pushed function: MakeLoadedWidowMoveButton
        function MakeLoadedWidowMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrWidow, app.Widowfin1, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                app.modelWidow.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Button pushed function: MakeLoadedUR3eMoveButton
        function MakeLoadedUR3eMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR3e, app.UR3efin2, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR3e.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.EditField.Value = changingValue; % Set the edit field value to the slider value
            currentPos = app.modelWidow.model.getpos();
            currentPos(1) = changingValue;
            app.modelWidow.model.animate(currentPos);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value < app.Joint1Slider.Limits(1) || value > app.Joint1Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value = max(app.Joint1Slider.Limits(1), min(value, app.Joint1Slider.Limits(2)));
                app.EditField.Value = value;
            end
            app.Joint1Slider.Value = value; % Set the slider value to the edit field value
            currentPos = app.modelWidow.model.getpos();
            currentPos(1) = value;
            app.modelWidow.model.animate(currentPos);

        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.EditField.Value = changingValue; % Set the edit field value to the slider value
            currentPos = app.modelWidow.model.getpos();
            currentPos(2) = changingValue;
            app.modelWidow.model.animate(currentPos);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if value < app.Joint1Slider.Limits(1) || value > app.Joint1Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value = max(app.Joint1Slider.Limits(1), min(value, app.Joint1Slider.Limits(2)));
                app.EditField.Value = value;
            end
            app.Joint1Slider.Value = value; % Set the slider value to the edit field value
            currentPos = app.modelWidow.model.getpos();
            currentPos(2) = value;
            app.modelWidow.model.animate(currentPos);
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue; % Set the edit field value to the slider value
            currentPos = app.modelWidow.model.getpos();
            currentPos(3) = changingValue;
            app.modelWidow.model.animate(currentPos);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            if value < app.Joint3Slider.Limits(1) || value > app.Joint3Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value = max(app.Joint3Slider.Limits(1), min(value, app.Joint3Slider.Limits(2)));
                app.EditField_3.Value = value;
            end
            app.Joint3Slider.Value = value; % Set the slider value to the edit field value
            currentPos = app.modelWidow.model.getpos();
            currentPos(3) = value;
            app.modelWidow.model.animate(currentPos);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.XLim = [-1.1 1.1];
            app.plot.YLim = [-1.1 1.1];
            app.plot.ZLim = [0 1];
            app.plot.Tag = 'axes1';
            app.MakeLoadedWidowMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedWidowMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedWidowMoveButtonPushed, true);
            app.MakeLoadedWidowMoveButton.Text = 'Make Loaded Widow Move';
            app.MakeLoadedUR3eMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedUR3eMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR3eMoveButtonPushed, true);
            app.MakeLoadedUR3eMoveButton.Text = 'Make Loaded UR3e Move';
            app.Joint1SliderLabel = uilabel(app.figure1);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.figure1);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.MajorTicks = [-180 180];
            app.Joint1Slider.MajorTickLabels = {'-180', '180'};
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = 0;
            app.Lamp = uilamp(app.figure1);
            app.RESUMEButton = uibutton(app.figure1, 'push');
            app.RESUMEButton.Text = 'RESUME';
            app.EditField = uieditfield(app.figure1, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.figure1, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.figure1, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.figure1, 'numeric');
            app.EditField_5 = uieditfield(app.figure1, 'numeric');
            app.EditField_6 = uieditfield(app.figure1, 'numeric');
            app.DISENGAGEESTOPButton = uibutton(app.figure1, 'push');
            app.DISENGAGEESTOPButton.Text = 'DISENGAGE E-STOP';
            app.EMERGENCYSTOPButton = uibutton(app.figure1, 'push');
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.Joint2SliderLabel = uilabel(app.figure1);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.figure1);
            app.Joint2Slider.Limits = [-108 114];
            app.Joint2Slider.MajorTicks = [-108 0 114];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.figure1);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.figure1);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.MajorTicks = [-180 0 180];
            app.Joint5SliderLabel = uilabel(app.figure1);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.figure1);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.MajorTicks = [-180 0 180];
            app.Joint4SliderLabel = uilabel(app.figure1);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.figure1);
            app.Joint4Slider.Limits = [-100 123];
            app.Joint4Slider.MajorTicks = [-100 0 123];
            app.Joint3SliderLabel = uilabel(app.figure1);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.figure1);
            app.Joint3Slider.Limits = [-123 92];
            app.Joint3Slider.MajorTicks = [-123 0 92];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.XEditFieldLabel = uilabel(app.figure1);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.figure1, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.figure1);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.figure1, 'numeric');
            app.ZEditFieldLabel = uilabel(app.figure1);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.figure1, 'numeric');
            app.REditFieldLabel = uilabel(app.figure1);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.figure1, 'numeric');
            app.PEditFieldLabel = uilabel(app.figure1);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.figure1, 'numeric');
            app.YEditField_2Label = uilabel(app.figure1);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.figure1, 'numeric');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end